classdef Access2Electricity < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        Problem1Tab              matlab.ui.container.Tab
        Load                     matlab.ui.control.Image
        SMRRange                 matlab.ui.control.Label
        CountriestoShowLabel     matlab.ui.control.Label
        StartingMeanRankLabel    matlab.ui.control.Label
        ElectricAccessibilityvsGDPLabel  matlab.ui.control.Label
        Prob1_StartingMeanRank   matlab.ui.control.NumericEditField
        Prob1_GraphButton        matlab.ui.control.Button
        Prob1_CountriestoShow    matlab.ui.control.NumericEditField
        Prob1_YearRange          matlab.ui.control.RangeSlider
        Prob1_Graph              matlab.ui.control.UIAxes
        Prob1_Graph2             matlab.ui.control.UIAxes
        Problem2Tab              matlab.ui.container.Tab
        Prob2_GraphButton        matlab.ui.control.Button
        Load2                    matlab.ui.control.Image
        Prob2_YearChange         matlab.ui.control.DropDown
        SelectYearDropDownLabel  matlab.ui.control.Label
        Prob2_CountryChange      matlab.ui.control.DropDown
        SelectCountryLabel       matlab.ui.control.Label
        Prob2_Graph_2            matlab.ui.control.UIAxes
        Prob2_Graph_3            matlab.ui.control.UIAxes
        Prob2_Graph              matlab.ui.control.UIAxes
        Problem3Tab              matlab.ui.container.Tab
        Load3                    matlab.ui.control.Image
        Prob3_Geoplot            matlab.ui.container.Panel
        Prob3_RadioButtons       matlab.ui.container.ButtonGroup
        thMostAccessibleButton   matlab.ui.control.RadioButton
        ndMostAccesibleButton    matlab.ui.control.RadioButton
        stMostAccessibleButton   matlab.ui.control.RadioButton
        rdMostAccessibleButton   matlab.ui.control.RadioButton
        Problem4Tab              matlab.ui.container.Tab
        Load4                    matlab.ui.control.Image
        Prob4_GraphButton        matlab.ui.control.Button
        Prob4_IncomeStatus2      matlab.ui.control.DropDown
        Prob4_YearRange          matlab.ui.control.RangeSlider
        Prob4_IncomeStatus1      matlab.ui.control.DropDown
        Prob4_Graph              matlab.ui.control.UIAxes
    end


    properties (Access = public)
        Prop_avgLI;
        Prop_standLI;
        Prop_avgHI;
        Prop_standHI;
        Prop_avgUMI;
        Prop_standUMI;
        Prop_avgLMI;
        Prop_standLMI;
        xMin = 1990
        xMax = 2023
        numYears = 0
        ybars;
        choice1a=0;
        choice1b=0;
        choice2a=0;
        choice2b=0;
        x = 0
        buttPressed = 0
        sCountries=1
        numCountries=266
        p1y;
        p1USy;
        p1USy2;
        p1y2;
        selection="";
        positions2;
        percentCoal2;
        percentOil2;
        percentNuclear2;
        percentRenewable2;
        countries2={}
        regions2={}
        start = 0
        CountrySelected
        p2year
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
         %% LOADING GIF CODE VVVVV
            if app.start == 0
                app.Load.Visible = 'on';
                app.Prob1_Graph2.Visible = 'off';
                app.Prob1_Graph.Visible = 'off';
                app.Prob1_YearRange.Visible = 'off';
                app.Prob1_GraphButton.Visible = 'off';
                app.Prob1_StartingMeanRank.Visible = 'off';
                app.Prob1_CountriestoShow.Visible = 'off';
                app.StartingMeanRankLabel.Visible = 'off';
                app.CountriestoShowLabel.Visible = 'off';
                app.ElectricAccessibilityvsGDPLabel.Visible = 'off';

                app.Load2.Visible = 'on';
                app.Prob2_Graph.Visible = 'off';

                app.Load3.Visible = 'on';
                app.Prob3_Geoplot.Visible = 'off';
                app.Prob3_RadioButtons.Visible = 'off';

                app.Load4.Visible = 'on';
                app.Prob4_Graph.Visible = 'off';
                app.Prob4_IncomeStatus1.Visible = 'off';
                app.Prob4_IncomeStatus2.Visible = 'off';
                app.Prob4_GraphButton.Visible = 'off';
                app.Prob4_YearRange.Visible = 'off';

                pause(1)

                app.Load.Visible = 'off';
                app.Prob1_Graph2.Visible = 'on';
                app.Prob1_Graph.Visible = 'on';
                app.Prob1_YearRange.Visible = 'on';
                app.Prob1_GraphButton.Visible = 'on';
                app.Prob1_StartingMeanRank.Visible = 'on';
                app.Prob1_CountriestoShow.Visible = 'on';
                app.StartingMeanRankLabel.Visible = 'on';
                app.CountriestoShowLabel.Visible = 'on';
                app.ElectricAccessibilityvsGDPLabel.Visible = 'on';

                app.Load2.Visible = 'off';
                app.Prob2_Graph.Visible = 'on';

                app.Load3.Visible = 'off';
                app.Prob3_Geoplot.Visible = 'on';
                app.Prob3_RadioButtons.Visible = 'on';

                app.Load4.Visible = 'off';
                app.Prob4_Graph.Visible = 'on';
                app.Prob4_IncomeStatus1.Visible = 'on';
                app.Prob4_IncomeStatus2.Visible = 'on';
                app.Prob4_GraphButton.Visible = 'on';
                app.Prob4_YearRange.Visible = 'on';
            end

            app.start = app.start + 1;


            %% PROBLEM 2

            % only a preselected year and country selected was needed for
            % problem 2
            app.p2year=2019;
            app.CountrySelected="Afghanistan";

            % public properties are used to transfer variables across
            % functions

            %% PROBLEM 3


            % loading in the gdp data using readcell 
            struct=readcell("API_EG.ELC.ACCS.ZS_DS2_en_excel_v2_42577.xls");
            struct2=readcell("MetaData_Indicators.xlsx");
            struct3=readcell("Production_CoalSources.xls");
            struct4=readcell("Production_OilSources.xls");
            struct5=readcell("Production_NuclearSources.xls");
            struct6=readcell("Production_Renewable.xls");
            struct10=readcell('LONGLAT.xlsx');

            % then we select certain areas of the datasets we want to
            % examine specifically in cell arrays below which are labeled
            % by relevance
            accessElectricity=struct(5:270,5:69);
            countries=struct(5:270,1);
            positions=struct10(2:245,2:3);
            positionsNames=struct10(2:245,4);
            percentCoal=struct3(5:270,5:68);
            percentOil=struct4(5:270,5:68);
            percentNuclear=struct5(5:270,5:68);
            percentRenewable=struct6(5:270,5:68);
            regions=struct2(2:267,2);


            % the functions below are used to erase any "missing values", a
            % traditional for loop was not used because of complications
            % referencing the cell arrays with {} brackets
            mask = cellfun(@ismissing, accessElectricity);
            accessElectricity(mask) = {0};
            accessElectricity=cell2mat(accessElectricity);

            mask = cellfun(@ismissing, positions);
            positions(mask) = {0};
            positions=cell2mat(positions);

            mask = cellfun(@ismissing, percentCoal);
            percentCoal(mask) = {0};
            percentCoal=cell2mat(percentCoal);

            mask = cellfun(@ismissing, percentOil);
            percentOil(mask) = {0};
            percentOil=cell2mat(percentOil);

            mask = cellfun(@ismissing, percentNuclear);
            percentNuclear(mask) = {0};
            percentNuclear=cell2mat(percentNuclear);

            mask = cellfun(@ismissing, percentRenewable);
            percentRenewable(mask) = {0};
            percentRenewable=cell2mat(percentRenewable);

            % all arrays are converted to matrixes and made with all types
            % of a single data set (double, string, number)

            % this variable is used as placeholders for a for loop
            % LL name is being used to hold the name of the country with
            % longitude and latitude values (these values are from an
            % outside dataset, which may not have the same countries in the
            % same order as the other dataset)
            LLname='';

            % the OG name is being used to hold the name of the country
            % from the dataset that is being orginally used, which is the
            % same for all the matrixes of different types of energy
            % (nuclear, oil, coal)
            OGname='';


            % the k will be used as a counter for a new matrix that will be
            % used for geoplot function
            k=1;

            % the for loop goes through both matrixes to find matches, and
            % when a match is found, it will be added with all its data
            % points to second matrixes (indicated by the number 2)
            for i=1:244
                for j=1:266
                    LLname=lower(convertCharsToStrings(positionsNames(i,1)));
                    OGname=lower(convertCharsToStrings(countries(j,1)));
                    if OGname == LLname
                        app.countries2(k,1)=(countries(j,1));
                        app.positions2(k,:)=positions(i,:);
                        app.percentCoal2(k,:)=percentCoal(j,56);
                        app.percentOil2(k,:)=percentOil(j,56);
                        app.percentNuclear2(k,:)=percentNuclear(j,56);
                        app.percentRenewable2(k,:)=percentRenewable(j,56);
                        app.regions2(k,1)=(regions(j,1));
                        k=k+1;
                    end
                end
            end


            %% PROBLEM 4
            % the data will be loaded in again to reduce any errors of the
            % cell arrays being manipulated from button function and value
            % changes 
            struct=readcell("API_EG.ELC.ACCS.ZS_DS2_en_excel_v2_42577.xls");
            struct2=readcell("MetaData_Indicators.xlsx");
            struct3=readcell("Production_CoalSources.xls");
            struct4=readcell("Production_OilSources.xls");
            struct5=readcell("Production_NuclearSources.xls");
            struct6=readcell("Production_Renewable.xls");
            struct7=readcell("Production_Urban");
            struct8=readcell("Production_Rural");


            countries=struct(5:270,1);
            income=struct2(2:267,3);
            accessElectricity=struct(5:270,5:68);
            

            % the matrixes are being filtered for their "missing values"
            % once again with these lines
            mask = cellfun(@ismissing, accessElectricity);
            accessElectricity(mask) = {0};
            accessElectricity=cell2mat(accessElectricity);

            % place holder matrixes are created to hold the index number
            % for matrixes of countries that are classified from different
            % incomes

            % HI = high income
            % UMI = upper middle income
            % LMI = lower middle income
            % LI = lower income
            indexNumHI=[];
            indexNumUMI=[];
            indexNumLMI=[];
            indexNumLI=[];

            % more placeholders are being used again for the forloops to
            % seperate the countries by their incomes that have been
            % filtered
            j=1;
            g=1;
            h=1;
            n=1;


            % this for loop will search through the original matrixes that
            % have been converted. We search for the income by searching
            % for a matrix that only includes the income column in one
            % whole data set, therefore the strings can be compared
            for i = 1:length(income)
                if income{i}== "High income"
                    indexNumHI(j)=i;
                    j=j+1;
                elseif income{i}=="Upper middle income"
                    indexNumUMI(g)=i;
                    g=g+1;
                elseif income{i}=="Lower middle income"
                    indexNumLMI(h)=i;
                    h=h+1;
                elseif income{i}=="Low income"
                    indexNumLI(n)=i;
                    n=n+1;
                end
            end

            % when a match is found, using the counters we are able to
            % create another new matrix to be used to graph 

            % preallocating y matrixes for problem 4 
            p4y2=zeros(1,64);
            p4y3=zeros(1,64);
            p4y4=zeros(1,64);
            p4y5=zeros(1,64);

            % another place holder for a counter is used to differentiate
            % the values being used 
            % all the for loops below finally access the data from the
            % years 1960 - 2023 into a new matrixes, where all the
            % countries will be classified by the same income
            k=1;
            for i = indexNumHI
                for j = 1:64
                    p4y2(k, j)=accessElectricity(i,j);
                end
                k=k+1;
            end

            k=1;
            for i = indexNumLI
                for j = 1:64
                    p4y3(k, j)=accessElectricity(i,j);
                end
                k=k+1;
            end
            k=1;
            for i = indexNumUMI

                for j = 1:64
                    p4y4(k, j)=accessElectricity(i,j);
                end
                k=k+1;
            end
            k=1;
            for i = indexNumLMI
                for j = 1:64
                    p4y5(k, j)=accessElectricity(i,j);
                end
                k=k+1;
            end

            % the rize and columns for the matrixes for each respective
            % income is found using the size function and stored in these
            % variables
            [nRow,nCol]=size(p4y2);

            % these variables are used for each for loop of each income to
            % act as the matrixes containing values for each year of their
            % average, and standard deviation of ALL the countries that are
            % classfied with that income (all high income countries will be
            % averaged out for one year to be displayed on the bar graph) 
            avgHI=[];
            standHI=[];

            % another place holder is created for these new matrixes
            g=0;
            for j = 1:nCol
                g=g+1;
                sum=0;
                for i=1:nRow
                    sum=sum+p4y2(i,j);
                end
                avgHI(g)=sum/82;
                standHI(g)=std(p4y2(:,j));
            end

            % inside these for loops, a calculation occurs for standard
            % deviations and means
            [nRow,nCol]=size(p4y5);
            avgLMI=[];
            standLMI=[];
            g=0;
            for j = 1:nCol
                g=g+1;
                sum=0;
                for i=1:nRow
                    sum=sum+p4y5(i,j);
                end
                avgLMI(g)=sum/54;
                standLMI(g)=std(p4y5(:,j));
            end

            [nRow,nCol]=size(p4y4);
            avgUMI=[];
            standUMI=[];
            g=0;
            for j = 1:nCol
                sum=0;
                g=g+1;
                for i=1:nRow
                    sum=sum+p4y4(i,j);
                end
                avgUMI(g)=sum/54;
                standUMI(g)=std(p4y4(:,j));
            end


            [nRow,nCol]=size(p4y3);
            avgLI=[];
            standLI=[];
            g=0;
            for j = 1:nCol
                sum=0;
                g=g+1;
                for i=1:nRow
                    sum=sum+p4y3(i,j);
                end
                avgLI(g)=sum/26;
                standLI(g)=std(p4y3(:,j));
            end

            % these final resulting matrixes are stored in properties that
            % can accessed across all functions across the whole app for
            % value changes 
            app.Prop_avgLI = avgLI;
            app.Prop_standLI = standLI;
            app.Prop_avgHI = avgHI;
            app.Prop_standHI = standHI;
            app.Prop_avgUMI = avgUMI;
            app.Prop_standUMI = standUMI;
            app.Prop_avgLMI = avgLMI;
            app.Prop_standLMI = standLMI;

            % these are values that are preset for low income (because that
            % is the value that is defaulted on the app gui)
            app.choice1a = app.Prop_avgLI;
            app.choice1b = app.Prop_standLI;
            app.choice2a = app.Prop_avgLI;
            app.choice2b = app.Prop_standLI;

            % these are preset values based on the what the year slider is
            % originally set to upon startup
            app.xMin = 1990;
            app.xMax = 1990;
            app.numYears = app.xMax-app.xMin+1;
            app.x=linspace(app.xMin,app.xMax,app.numYears);


        end

        % Value changed function: Prob1_YearRange
        function Prob1_YearRangeValueChanged(app, event)
            % when the user changes the year, each year will be stored in
            %  VVVVVV two seperate public variables VVVVVV
            app.xMin = app.Prob1_YearRange.Value(1);
            app.xMax = app.Prob1_YearRange.Value(2);

            % then the years will be used to calculate a numYears variable,
            % which will be used for the linspace function
            app.numYears = app.xMax-app.xMin+1;

            % a x array is created, this is the years being calculated
            % through linspace function in a x array with the same number
            % of elements as the user specifies for the bar graph
            app.x=linspace(app.xMin,app.xMax,app.numYears);
        end

        % Button pushed function: Prob1_GraphButton
        function Prob1_GraphButtonPushed(app, event)
            %% PROBLEM 1 BUTTON

            % first we allow the code to be generated with a pause
            % the data is loaded in again to erase any manipulations from
            % previous button changes and functions
            pause(1)
            struct9=readcell("API_NY.GDP.MKTP.CD_DS2_en_excel_v2_42476.xls");
            struct=readcell("API_EG.ELC.ACCS.ZS_DS2_en_excel_v2_42577.xls");

            % the specified areas of the code are set into new cell arrays
            GDPValue=struct9(5:270,5:69);
            accessElectricity=struct(5:270,5:69);
            countries=struct(5:270,1);
            mean=struct(5:270,69);

            % this function removes any "missing values" from the cell
            % arrays, so that it is able to be converted to matrix for
            % conditional and for loop purposes
            mask = cellfun(@ismissing, accessElectricity);
            accessElectricity(mask) = {0};
            accessElectricity=cell2mat(accessElectricity);

            mask = cellfun(@ismissing, GDPValue);
            GDPValue(mask) = {0};
            GDPValue=cell2mat(GDPValue);

            mask =cellfun(@ismissing,mean);
            mean(mask) = {0};
            mean=cell2mat(mean);

            % the n variable will be used for the length of the datasets
            N=266;

            % this bubble sort below will sort the already created mean
            % column of the dataset, and sort the data set by the highest
            % means to lowest means (the countries will be ranked by
            % highest mean and lowest means rather than their name)
            hasSwaps=true;
            while hasSwaps
                hasSwaps=false;
                for k=1:N-1
                    if mean(k) < mean(k+1)
                        temp=mean(k);
                        mean(k)=mean(k+1);
                        mean(k+1)=temp;
                        hasSwaps=true;
                    end
                end
            end

            % this matrix will be a new matrix used for obtaining
            % information from the selection being made from the user on
            % which rank they want to start with
            accessElectricity2=accessElectricity;


            % the selection variable is being used to obtain the mean,
            % since only the means are sorted by highest to lowest, this
            % index will be searched for in the original data set and then
            % changed into a new matrix using the j counter, to rank them
            % correctly with the original datapoints of all the years
            for i = 1:266
                selection=mean(i);
                j=1;
                keepgoing=1;
                while keepgoing==1
                    if accessElectricity(j,65)==selection
                        index=j;
                        accessElectricity2(i,:)=accessElectricity(index,:);
                        keepgoing=0;
                    else
                        j=j+1;
                    end
                end
            end

            % more data cell arrays are being loaded in and cleared for
            % the process to repeat, but instead with a dataset that
            % includes GDP values for all countries again
            mean=struct9(5:270,69);
            mask =cellfun(@ismissing,mean);
            mean(mask) = {0};
            mean=cell2mat(mean);

            N=266;
            hasSwaps=true;
            while hasSwaps
                hasSwaps=false;
                for k=1:N-1
                    if mean(k) < mean(k+1)
                        temp=mean(k);
                        mean(k)=mean(k+1);
                        mean(k+1)=temp;
                        hasSwaps=true;
                    end
                end
            end

            GDPValue2=GDPValue;
            for i = 1:266
                selection=mean(i);
                j=1;
                keepgoing=1;
                while keepgoing==1
                    if GDPValue(j,65)==selection
                        index=j;
                        GDPValue2(i,:)=GDPValue(index,:);
                        keepgoing=0;
                    else
                        j=j+1;
                    end
                end
            end
            % finally, the dataset once filtered will include all countries
            % ranked from highest mean to lowest mean. Therefore using
            % properties that are public from the numeric text fields
            % avaliable, only the data specified by the user will be
            % plotted below
            app.p1y=accessElectricity2(app.sCountries:(app.sCountries+app.numCountries-1),app.xMin-1959:app.xMax-1959);
            app.p1y2=GDPValue2(app.sCountries:(app.sCountries+app.numCountries-1),app.xMin-1959:app.xMax-1959);
            app.p1USy=accessElectricity2(1,app.xMin-1959:app.xMax-1959);
            app.p1USy2=GDPValue2(1,app.xMin-1959:app.xMax-1959);
            plot(app.Prob1_Graph, app.x, app.p1y, 'o-', 'LineWidth', 2);
            plot(app.Prob1_Graph2, app.x, app.p1y2, 'o-', 'LineWidth', 2);
        end

        % Value changed function: Prob1_CountriestoShow
        function Prob1_CountriestoShowValueChanged(app, event)
            
            % the variable numCountries stores the new value
            value = app.Prob1_CountriestoShow.Value;
            app.numCountries=value;

            % the calculation is used to safe proof the amount of countries
            % that can be shown with the conditional
            % (if the first country's rank is 260, and 10 countries are
            % typed in to be shown, it will show 6 because the max amount
            % that can be shown is 260
            calculation = 266 - app.sCountries;
            if value > calculation
                app.numCountries = calculation;
                app.Prob1_CountriestoShow.Value = calculation;
            end

        end

        % Value changed function: Prob1_StartingMeanRank
        function Prob1_StartingMeanRankValueChanged(app, event)
            
            % the variable sCountires stores the new value 
            value = app.Prob1_StartingMeanRank.Value;
            app.sCountries=value;

            % this conditional is used to safe proof the values that can be
            % typed in, if the user selects a number outside the range of
            % 266 and 0 it will not restart back to 0 for the user to pick
            % a new value
            if app.sCountries > 266 || app.sCountries < 0
                app.sCountries = 0;
                app.Prob1_StartingMeanRank.Value = 0;
            else
                app.SMRRange.Visible = 'off';
            end
            Prob1_CountriestoShowValueChanged(app)
        end

        % Value changed function: Prob2_CountryChange
        function Prob2_CountryChangeValueChanged(app, event)
            
            % the country selected is changed and stored in this value
            app.CountrySelected = app.Prob2_CountryChange.Value;

        end

        % Value changed function: Prob2_YearChange
        function Prob2_YearChangeValueChanged(app, event)
            
            % the year is selected and changed and stored in the variables
            % which will be used to display the correct year in the
            % corresponding dataset
            app.p2year = app.Prob2_YearChange.Value;
            app.p2year = str2double(app.p2year);

        end

        % Button pushed function: Prob2_GraphButton
        function Prob2_GraphButtonPushed(app, event)
            
            
            % the data is loaded again after the button pushed to remove
            % any manipulations that have occured within the value changes
            % and functions
            struct=readcell("API_EG.ELC.ACCS.ZS_DS2_en_excel_v2_42577.xls");
            struct7=readcell("Production_Urban");
            struct8=readcell("Production_Rural");
            struct11=readcell("API_SI.POV.DDAY_DS2_en_excel_v2_122.xls");


            % only the areas needed are selected and declared into new cell
            % arrays with corresponding data in corresponding datasets
            countries=struct(5:270,1);
            percentUrban=struct7(5:270,5:68);
            percentRural=struct8(5:270,5:68);
            percentPoverty=struct11(5:270,5:68);

            % these functions are used to filter all the values that are
            % "is missing" and convert the cell array into a matrix 
            mask = cellfun(@ismissing, percentRural);
            percentRural(mask) = {0};
            percentRural=cell2mat(percentRural);

            mask = cellfun(@ismissing, percentUrban);
            percentUrban(mask) = {0};
            percentUrban=cell2mat(percentUrban);

            mask = cellfun(@ismissing, percentPoverty);
            percentPoverty(mask) = {0};
            percentPoverty=cell2mat(percentPoverty);


            % the size of the cell array of all the countries' names is
            % obtained by the function and will be used for the loop
            [nRow, nCol]=size(countries);

            % the country selected will be converted to a string with all
            % lowercase letters to allow for comparisons in a conditional
            app.CountrySelected=lower(convertCharsToStrings(app.CountrySelected));

            % the for loop will search for the users choice of country
            % selected (string) 
            for i = 1:nRow
                if lower(convertCharsToStrings(countries{i})) == app.CountrySelected
                    indexNum = i;
                end
            end


            % the matrix with the index num and years selected will be
            % imported into one single variable (matrix) that will be used
            % for pie chart program
            DataSelected=[percentUrban(indexNum, app.p2year-1959),100-percentUrban(indexNum,app.p2year-1959),percentRural(indexNum, app.p2year-1959),100-percentRural(indexNum,app.p2year-1959)];
            pie3(app.Prob2_Graph, DataSelected(1,1:2),ones(1,2),["Access", "No Access"])
            pie3(app.Prob2_Graph_2, DataSelected(1,3:4), ones(1,2),["Access", "No Access"])
            plot(app.Prob2_Graph_3,[linspace(1960,2023,64)], percentPoverty(indexNum,:), 'b-', LineWidth=1.75)
        end

        % Selection changed function: Prob3_RadioButtons
        function Prob3_RadioButtonValueChanged(app, event)

            %% PROBLEM 3 PROBLEM 


            % loading in the code needed for a geoplot
            gx=geoaxes(app.Prob3_Geoplot);

            % loading in the datasets again to remove any manipulation that
            % has been done by other function and changed values
            struct=readcell("API_EG.ELC.ACCS.ZS_DS2_en_excel_v2_42577.xls");
            struct2=readcell("MetaData_Indicators.xlsx");
            struct3=readcell("Production_CoalSources.xls");
            struct4=readcell("Production_OilSources.xls");
            struct5=readcell("Production_NuclearSources.xls");
            struct6=readcell("Production_Renewable.xls");
            struct7=readcell("Production_Urban");
            struct8=readcell("Production_Rural");
            struct10=readcell('LONGLAT.xlsx');

            % only data neeeded to be accessed at specified areas of the
            % cell arrays will be declared in these matrixes 
            accessElectricity=struct(5:270,5:69);
            countries=struct(5:270,1);
            positions=struct10(2:245,2:3);
            positionsNames=struct10(2:245,4);
            percentCoal=struct3(5:270,5:68);
            percentOil=struct4(5:270,5:68);
            percentNuclear=struct5(5:270,5:68);
            percentRenewable=struct6(5:270,5:68);
            regions=struct2(2:267,2);
            percentUrban=struct7(5:270,5:68);
            percentRural=struct8(5:270,5:68);


            % this function will be used to remove all the "missing values"
            % present inside all the matrixes from before and will convert
            % the cell array into an actual matrix
            mask = cellfun(@ismissing, accessElectricity);
            accessElectricity(mask) = {0};
            accessElectricity=cell2mat(accessElectricity);

            mask = cellfun(@ismissing, positions);
            positions(mask) = {0};
            positions=cell2mat(positions);

            mask = cellfun(@ismissing, percentCoal);
            percentCoal(mask) = {0};
            percentCoal=cell2mat(percentCoal);

            mask = cellfun(@ismissing, percentOil);
            percentOil(mask) = {0};
            percentOil=cell2mat(percentOil);

            mask = cellfun(@ismissing, percentNuclear);
            percentNuclear(mask) = {0};
            percentNuclear=cell2mat(percentNuclear);

            mask = cellfun(@ismissing, percentRenewable);
            percentRenewable(mask) = {0};
            percentRenewable=cell2mat(percentRenewable);


            % all arrays are converted to matrixes and made with all types
            % of a single data set (double, string, number)

            % this variable is used as placeholders for a for loop
            % LL name is being used to hold the name of the country with
            % longitude and latitude values (these values are from an
            % outside dataset, which may not have the same countries in the
            % same order as the other dataset)
            LLname='';

            % the OG name is being used to hold the name of the country
            % from the dataset that is being orginally used, which is the
            % same for all the matrixes of different types of energy
            % (nuclear, oil, coal)
            OGname='';

            % the k will be used as a counter for a new matrix that will be
            % used for geoplot function
            k=1;

            % the for loop goes through both matrixes to find matches, and
            % when a match is found, it will be added with all its data
            % points to second matrixes (indicated by the number 2)
            for i=1:244
                for j=1:266
                    LLname=lower(convertCharsToStrings(positionsNames(i,1)));
                    OGname=lower(convertCharsToStrings(countries(j,1)));
                    if OGname == LLname
                        countries2(k,1)=countries(j,1);
                        positions2(k,:)=positions(i,:);
                        percentCoal2(k,:)=percentCoal(j,56);
                        percentOil2(k,:)=percentOil(j,56);
                        percentNuclear2(k,:)=percentNuclear(j,56);
                        percentRenewable2(k,:)=percentRenewable(j,56);
                        regions2(k,1)=regions(j,1);
                        k=k+1;
                    end
                end
            end

            % this for loop will create a temporary matrix will all the
            % values that have been sorted. Each country that has a
            % longitude and latitude value has been sorted into the four
            % matrixes with their access to electricity for each type
            % of energy source being in the four new matrixes. The temp
            % matrix will sort each value from highest to lowest. 

            for i = 1:178
                tempMatrix=[];
                tempMatrix(1)=percentCoal2(i,1);
                tempMatrix(2)=percentOil2(i,1);
                tempMatrix(3)=percentNuclear2(i,1);
                tempMatrix(4)=percentRenewable2(i,1);
                N=4;
                hasSwaps=true;
                while hasSwaps
                    hasSwaps=false;
                    for k=1:N-1
                        if tempMatrix(k) < tempMatrix(k+1)
                            temp=tempMatrix(k);
                            tempMatrix(k)=tempMatrix(k+1);
                            tempMatrix(k+1)=temp;
                            hasSwaps=true;
                        end
                    end
                end

                % this conditional will then determine with this new matrix
                % which energy source (index) matches with the value of the
                % original data set including the types of energy values.
                % If the highest one is found the point will be plotted
                % with the corresponding color.
                if app.stMostAccessibleButton.Value == 1
                    if tempMatrix(:) == 0
                        p5=geoplot(gx,positions2(i,1)+5, positions2(i,2)-10, 'c*', LineWidth=2.5);
                        hold (gx, 'on')
                    elseif tempMatrix(1)==percentCoal2(i,1)
                        p1=geoplot(gx,positions2(i,1)+5, positions2(i,2)-10, 'ko', LineWidth=2.5);
                        hold (gx, 'on')
                    elseif tempMatrix(1)==percentOil2(i,1)
                        p2=geoplot(gx,positions2(i,1)+5, positions2(i,2)-10, 'bo', LineWidth=2.5);
                        hold (gx, 'on')
                    elseif tempMatrix(1)==percentNuclear2(i,1)
                        p3=geoplot(gx,positions2(i,1)+5, positions2(i,2)-10, 'ro', LineWidth=2.5);
                        hold (gx, 'on')
                    elseif tempMatrix(1)==percentRenewable2(i,1)
                        p4=geoplot(gx,positions2(i,1)+5, positions2(i,2)-10, 'go', LineWidth=2.5);
                        hold (gx, 'on')
                    end
                end
                % this repeats for the 2nd most used, 3rd most used, and
                % 4th most used values accordingly
                if app.ndMostAccesibleButton.Value == 1
                    if tempMatrix(:) == 0
                        p5=geoplot(gx,positions2(i,1)+5, positions2(i,2)-10, 'c*', LineWidth=2.5);
                        hold (gx, 'on')
                    elseif tempMatrix(2)==percentCoal2(i,1)
                        p1=geoplot(gx,positions2(i,1)+5, positions2(i,2)-10, 'ko', LineWidth=2.5);
                        hold (gx, 'on')
                    elseif tempMatrix(2)==percentOil2(i,1)
                        p2=geoplot(gx,positions2(i,1)+5, positions2(i,2)-10, 'bo', LineWidth=2.5);
                        hold (gx, 'on')
                    elseif tempMatrix(2)==percentNuclear2(i,1)
                        p3=geoplot(gx,positions2(i,1)+5, positions2(i,2)-10, 'ro', LineWidth=2.5);
                        hold (gx, 'on')
                    elseif tempMatrix(2)==percentRenewable2(i,1)
                        p4=geoplot(gx,positions2(i,1)+5, positions2(i,2)-10, 'go', LineWidth=2.5);
                        hold (gx, 'on')
                    end
                end
                if app.rdMostAccessibleButton.Value == 1
                    if tempMatrix(:) == 0
                        p5=geoplot(gx,positions2(i,1)+5, positions2(i,2)-10, 'c*', LineWidth=2.5);
                        hold (gx, 'on')
                    elseif tempMatrix(3)==percentCoal2(i,1)
                        p1=geoplot(gx,positions2(i,1)+5, positions2(i,2)-10, 'ko', LineWidth=2.5);
                        hold (gx, 'on')
                    elseif tempMatrix(3)==percentOil2(i,1)
                        p2=geoplot(gx,positions2(i,1)+5, positions2(i,2)-10, 'bo', LineWidth=2.5);
                        hold (gx, 'on')
                    elseif tempMatrix(3)==percentNuclear2(i,1)
                        p3=geoplot(gx,positions2(i,1)+5, positions2(i,2)-10, 'ro', LineWidth=2.5);
                        hold (gx, 'on')
                    elseif tempMatrix(3)==percentRenewable2(i,1)
                        p4=geoplot(gx,positions2(i,1)+5, positions2(i,2)-10, 'go', LineWidth=2.5);
                        hold (gx, 'on')
                    end
                end
                if app.thMostAccessibleButton.Value == 1
                    if tempMatrix(:) == 0
                        p5=geoplot(gx,positions2(i,1)+5, positions2(i,2)-10, 'c*', LineWidth=2.5);
                        hold (gx, 'on')
                    elseif tempMatrix(4)==percentCoal2(i,1)
                        p1=geoplot(gx,positions2(i,1)+5, positions2(i,2)-10, 'ko', LineWidth=2.5);
                        hold (gx, 'on')
                    elseif tempMatrix(4)==percentOil2(i,1)
                        p2=geoplot(gx,positions2(i,1)+5, positions2(i,2)-10, 'bo', LineWidth=2.5);
                        hold (gx, 'on')
                    elseif tempMatrix(4)==percentNuclear2(i,1)
                        p3=geoplot(gx,positions2(i,1)+5, positions2(i,2)-10, 'ro', LineWidth=2.5);
                        hold (gx, 'on')
                    elseif tempMatrix(4)==percentRenewable2(i,1)
                        p4=geoplot(gx,positions2(i,1)+5, positions2(i,2)-10, 'go', LineWidth=2.5);
                        hold (gx, 'on')
                    end
                end
            end

            % these last set of conditionals are used for the legends being
            % created, as complications arose because of single point for
            % loop plotting being used. 
            if  app.stMostAccessibleButton.Value == 1
                legend(gx, [p1,p2,p3,p4,p5], 'Coal', 'Oil', 'Nuclear','Renewable', 'None');
                hold (gx, 'off')
            end


            if app.ndMostAccesibleButton.Value == 1
                legend(gx, [p1,p2,p3,p4,p5], 'Coal', 'Oil', 'Nuclear','Renewable','None');
                hold (gx, 'off')
            end


            if app.rdMostAccessibleButton.Value == 1
                legend(gx, [p1,p2,p3,p4,p5], 'Coal', 'Oil', 'Nuclear', 'Renewable','None');
                hold (gx, 'off')
            end


            if app.thMostAccessibleButton.Value == 1
                legend(gx, [p1,p2,p3,p5], 'Coal', 'Oil', 'Nuclear','None');
                hold (gx, 'off')
            end
        end

        % Value changed function: Prob4_YearRange
        function Prob4_YearRangeValueChanged(app, event)
            % when the user changes the year, each year will be stored in
            %  VVVVVV two seperate public variables VVVVVV
            app.xMin = app.Prob4_YearRange.Value(1);
            app.xMax = app.Prob4_YearRange.Value(2);

            % then the years will be used to calculate a numYears variable,
            % which will be used for the linspace function
            app.numYears = app.xMax-app.xMin+1;

            % a x array is created, this is the years being calculated
            % through linspace function in a x array with the same number
            % of elements as the user specifies for the bar graph
            app.x=linspace(app.xMin,app.xMax,app.numYears);


        end

        % Value changed function: Prob4_IncomeStatus1
        function Prob4_IncomeStatus1ValueChanged(app, event)
            
            % first the variables are initialized for the conditionals
            value = app.Prob4_IncomeStatus1.Value;
            app.choice1a=0;
            app.choice1b=0;

            % the conditional will go through the value of the user's
            % choice and check which dataset to provide based on the income
            % given by the user
            if value == "Low Income"
                app.choice1a = app.Prop_avgLI;
                app.choice1b = app.Prop_standLI;
            elseif value == "Lower Middle Income"
                app.choice1a = app.Prop_avgLMI;
                app.choice1b = app.Prop_standLMI;
            elseif value == "Upper Middle Income"
                app.choice1a = app.Prop_avgUMI;
                app.choice1b = app.Prop_standUMI;
            elseif value == "High Income"
                app.choice1a = app.Prop_avgHI;
                app.choice1b = app.Prop_avgHI;
            end
  

        end

        % Value changed function: Prob4_IncomeStatus2
        function Prob4_IncomeStatus2ValueChanged(app, event)
            
            % first the variables are initialized for the conditionals
            value = app.Prob4_IncomeStatus2.Value;
            app.choice2a=0;
            app.choice2b=0;

            % the conditional will go through the value of the user's
            % choice and check which dataset to provide based on the income
            % given by the user
            if value == "Low Income"
                app.choice2a = app.Prop_avgLI;
                app.choice2b = app.Prop_standLI;
            elseif value == "Lower Middle Income"
                app.choice2a = app.Prop_avgLMI;
                app.choice2b = app.Prop_standLMI;
            elseif value == "Upper Middle Income"
                app.choice2a = app.Prop_avgUMI;
                app.choice2b = app.Prop_standUMI;
            elseif value == "High Income"
                app.choice2a = app.Prop_avgHI;
                app.choice2b = app.Prop_standHI;
            end

        end

        % Button pushed function: Prob4_GraphButton
        function Prob4_GraphButtonPushed(app, event)
            
            
            % the new variables with the correct data will be plotted based
            % on the user's choice of years determined by the slider change
            % valued function (index)
            pause(1)
            app.ybars = [app.choice1a(app.xMin-1959:app.xMax-1959);...
                app.choice1b(app.xMin-1959:app.xMax-1959);...
                app.choice2a(app.xMin-1959:app.xMax-1959);...
                app.choice2b(app.xMin-1959:app.xMax-1959)];

            % the bar graph will be created with the newly generated
            % linspace x vector and y index of the specified amount of
            % years and income (held in choice 1b 1a 2a 2b) a represents
            % the means and b represents the standard deviation
            b=bar(app.Prob4_Graph, app.x, app.ybars);
            ylim(app.Prob4_Graph,[0 130])

            % legend is used to differeniate the values 
            legend(app.Prob4_Graph, "1st Choice Avg", "1st Choice Std.Dev",...
                "2nd Choice Avg", "2nd Choice Std.Dev")

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Problem1Tab = uitab(app.TabGroup);
            app.Problem1Tab.Title = 'Problem 1';
            app.Prob1_Graph2 = uiaxes(app.Problem1Tab);
            xlabel(app.Prob1_Graph2, 'Years (1990-2023)')
            ylabel(app.Prob1_Graph2, 'Electricity Accessibility (%)')
            zlabel(app.Prob1_Graph2, 'Z')
            app.Prob1_Graph = uiaxes(app.Problem1Tab);
            xlabel(app.Prob1_Graph, 'Years (1990-2023)')
            ylabel(app.Prob1_Graph, 'GDP ')
            zlabel(app.Prob1_Graph, 'Z')
            app.Prob1_YearRange = uislider(app.Problem1Tab, 'range');
            app.Prob1_YearRange.Limits = [1990 2021];
            app.Prob1_YearRange.MajorTicks = [1990 1993.875 1997.75 2001.625 2005.5 2009.375 2013.25 2017.125 2021];
            app.Prob1_YearRange.ValueChangedFcn = createCallbackFcn(app, @Prob1_YearRangeValueChanged, true);
            app.Prob1_YearRange.Value = [1990 1990];
            app.Prob1_CountriestoShow = uieditfield(app.Problem1Tab, 'numeric');
            app.Prob1_CountriestoShow.ValueChangedFcn = createCallbackFcn(app, @Prob1_CountriestoShowValueChanged, true);
            app.Prob1_GraphButton = uibutton(app.Problem1Tab, 'push');
            app.Prob1_GraphButton.ButtonPushedFcn = createCallbackFcn(app, @Prob1_GraphButtonPushed, true);
            app.Prob1_GraphButton.Text = 'GRAPH';
            app.Prob1_StartingMeanRank = uieditfield(app.Problem1Tab, 'numeric');
            app.Prob1_StartingMeanRank.ValueChangedFcn = createCallbackFcn(app, @Prob1_StartingMeanRankValueChanged, true);
            app.ElectricAccessibilityvsGDPLabel = uilabel(app.Problem1Tab);
            app.ElectricAccessibilityvsGDPLabel.Text = 'Electric Accessibility vs GDP ';
            app.StartingMeanRankLabel = uilabel(app.Problem1Tab);
            app.StartingMeanRankLabel.Text = 'Starting Mean Rank';
            app.CountriestoShowLabel = uilabel(app.Problem1Tab);
            app.CountriestoShowLabel.Text = 'Countries to Show';
            app.SMRRange = uilabel(app.Problem1Tab);
            app.SMRRange.Text = 'Pick between (1 - 266)';
            app.Load = uiimage(app.Problem1Tab);
            app.Load.ImageSource = fullfile(pathToMLAPP, 'animated_loader_gif_n6b5x0.gif');
            app.Problem2Tab = uitab(app.TabGroup);
            app.Problem2Tab.Title = 'Problem 2';
            app.Prob2_Graph = uiaxes(app.Problem2Tab);
            title(app.Prob2_Graph, 'Accessibility of Urban')
            zlabel(app.Prob2_Graph, 'Z')
            app.Prob2_Graph_3 = uiaxes(app.Problem2Tab);
            title(app.Prob2_Graph_3, 'Percent of Population under the Poverty Line')
            xlabel(app.Prob2_Graph_3, 'Years (1990-2021)')
            ylabel(app.Prob2_Graph_3, 'Percentage of Population')
            zlabel(app.Prob2_Graph_3, 'Z')
            app.Prob2_Graph_2 = uiaxes(app.Problem2Tab);
            title(app.Prob2_Graph_2, 'Accessibility of Rural')
            zlabel(app.Prob2_Graph_2, 'Z')
            app.SelectCountryLabel = uilabel(app.Problem2Tab);
            app.SelectCountryLabel.Text = 'Select Country:';
            app.Prob2_CountryChange = uidropdown(app.Problem2Tab);
            app.Prob2_CountryChange.Items = {'Afghanistan', 'Africa Eastern and Southern', 'Africa Western and Central', 'Albania', 'Algeria', 'American Samoa', 'Andorra', 'Angola', 'Antigua and Barbuda', 'Arab World', 'Argentina', 'Armenia', 'Aruba', 'Australia', 'Austria', 'Azerbaijan', 'Bahamas, The', 'Bahrain', 'Bangladesh', 'Barbados', 'Belarus', 'Belgium', 'Belize', 'Benin', 'Bermuda', 'Bhutan', 'Bolivia', 'Bosnia and Herzegovina', 'Botswana', 'Brazil', 'British Virgin Islands', 'Brunei Darussalam', 'Bulgaria', 'Burkina Faso', 'Burundi', 'Cabo Verde', 'Cambodia', 'Cameroon', 'Canada', 'Caribbean small states', 'Cayman Islands', 'Central African Republic', 'Central Europe and the Baltics', 'Chad', 'Channel Islands', 'Chile', 'China', 'Colombia', 'Comoros', 'Congo, Dem. Rep.', 'Congo, Rep.', 'Costa Rica', 'Côte d''Ivoire', 'Croatia', 'Cuba', 'Curaçao', 'Cyprus', 'Czechia', 'Denmark', 'Djibouti', 'Dominica', 'Dominican Republic', 'Early-demographic dividend', 'East Asia & Pacific', 'East Asia & Pacific (excluding high income)', 'East Asia & Pacific (IDA & IBRD)', 'Ecuador', 'Egypt, Arab Rep.', 'El Salvador', 'Equatorial Guinea', 'Eritrea', 'Estonia', 'Eswatini', 'Ethiopia', 'Euro area', 'Europe & Central Asia', 'Europe & Central Asia (excluding high income)', 'Europe & Central Asia (IDA & IBRD)', 'European Union', 'Faroe Islands', 'Fiji', 'Finland', 'Fragile and conflict affected situations', 'France', 'French Polynesia', 'Gabon', 'Gambia, The', 'Georgia', 'Germany', 'Ghana', 'Gibraltar', 'Greece', 'Greenland', 'Grenada', 'Guam', 'Guatemala', 'Guinea', 'Guinea-Bissau', 'Guyana', 'Haiti', 'Heavily indebted poor countries (HIPC)', 'High income', 'Honduras', 'Hong Kong SAR, China', 'Hungary', 'IBRD only', 'Iceland', 'IDA & IBRD total', 'IDA blend', 'IDA only', 'IDA total', 'India', 'Indonesia', 'Iran, Islamic Rep.', 'Iraq', 'Ireland', 'Isle of Man', 'Israel', 'Italy', 'Jamaica', 'Japan', 'Jordan', 'Kazakhstan', 'Kenya', 'Kiribati', 'Korea, Dem. People''s Rep.', 'Korea, Rep.', 'Kosovo', 'Kuwait', 'Kyrgyz Republic', 'Lao PDR', 'Late-demographic dividend', 'Latin America & Caribbean', 'Latin America & Caribbean (excluding high income)', 'Latin America & Caribbean (IDA & IBRD)', 'Latvia', 'Least developed countries: UN classification', 'Lebanon', 'Lesotho', 'Liberia', 'Libya', 'Liechtenstein', 'Lithuania', 'Low & middle income', 'Low income', 'Lower middle income', 'Luxembourg', 'Macao SAR, China', 'Madagascar', 'Malawi', 'Malaysia', 'Maldives', 'Mali', 'Malta', 'Marshall Islands', 'Mauritania', 'Mauritius', 'Mexico', 'Micronesia, Fed. Sts.', 'Middle East & North Africa', 'Middle East & North Africa (excluding high income)', 'Middle East & North Africa (IDA & IBRD)', 'Middle income', 'Moldova', 'Monaco', 'Mongolia', 'Montenegro', 'Morocco', 'Mozambique', 'Myanmar', 'Namibia', 'Nauru', 'Nepal', 'Netherlands', 'New Caledonia', 'New Zealand', 'Nicaragua', 'Niger', 'Nigeria', 'North America', 'North Macedonia', 'Northern Mariana Islands', 'Norway', 'Not classified', 'OECD members', 'Oman', 'Other small states', 'Pacific island small states', 'Pakistan', 'Palau', 'Panama', 'Papua New Guinea', 'Paraguay', 'Peru', 'Philippines', 'Poland', 'Portugal', 'Post-demographic dividend', 'Pre-demographic dividend', 'Puerto Rico', 'Qatar', 'Romania', 'Russian Federation', 'Rwanda', 'Samoa', 'San Marino', 'São Tomé and Principe', 'Saudi Arabia', 'Senegal', 'Serbia', 'Seychelles', 'Sierra Leone', 'Singapore', 'Sint Maarten (Dutch part)', 'Slovak Republic', 'Slovenia', 'Small states', 'Solomon Islands', 'Somalia', 'South Africa', 'South Asia', 'South Asia (IDA & IBRD)', 'South Sudan', 'Spain', 'Sri Lanka', 'St. Kitts and Nevis', 'St. Lucia', 'St. Martin (French part)', 'St. Vincent and the Grenadines', 'Sub-Saharan Africa', 'Sub-Saharan Africa (excluding high income)', 'Sub-Saharan Africa (IDA & IBRD)', 'Sudan', 'Suriname', 'Sweden', 'Switzerland', 'Syrian Arab Republic', 'Tajikistan', 'Tanzania', 'Thailand', 'Timor-Leste', 'Togo', 'Tonga', 'Trinidad and Tobago', 'Tunisia', 'Türkiye', 'Turkmenistan', 'Turks and Caicos Islands', 'Tuvalu', 'Uganda', 'Ukraine', 'United Arab Emirates', 'United Kingdom', 'United States', 'Upper middle income', 'Uruguay', 'Uzbekistan', 'Vanuatu', 'Venezuela, RB', 'Viet Nam', 'Virgin Islands (U.S.)', 'West Bank and Gaza', 'World', 'Yemen, Rep.', 'Zambia', 'Zimbabwe'};
            app.Prob2_CountryChange.ValueChangedFcn = createCallbackFcn(app, @Prob2_CountryChangeValueChanged, true);
            app.Prob2_CountryChange.Value = 'Afghanistan';
            app.SelectYearDropDownLabel = uilabel(app.Problem2Tab);
            app.SelectYearDropDownLabel.Text = 'Select Year: ';
            app.Prob2_YearChange = uidropdown(app.Problem2Tab);
            app.Prob2_YearChange.Items = {'1990', '1991', '1992', '1993', '1994', '1995', '1996', '1997', '1998', '1999', '2000', '2001', '2002', '2003', '2004', '2005', '2006', '2007', '2008', '2009', '2010', '2011', '2012', '2013', '2014', '2015', '2016', '2017', '2018', '2019', '2020', '2021'};
            app.Prob2_YearChange.ValueChangedFcn = createCallbackFcn(app, @Prob2_YearChangeValueChanged, true);
            app.Prob2_YearChange.Value = '2019';
            app.Load2 = uiimage(app.Problem2Tab);
            app.Load2.ImageSource = fullfile(pathToMLAPP, 'animated_loader_gif_n6b5x0.gif');
            app.Prob2_GraphButton = uibutton(app.Problem2Tab, 'push');
            app.Prob2_GraphButton.ButtonPushedFcn = createCallbackFcn(app, @Prob2_GraphButtonPushed, true);
            app.Prob2_GraphButton.Text = 'GRAPH';
            app.Problem3Tab = uitab(app.TabGroup);
            app.Problem3Tab.Title = 'Problem 3';
            app.Prob3_RadioButtons = uibuttongroup(app.Problem3Tab);
            app.Prob3_RadioButtons.SelectionChangedFcn = createCallbackFcn(app, @Prob3_RadioButtonValueChanged, true);
            app.Prob3_RadioButtons.Title = 'Select which rank of the most used energy type';
            app.rdMostAccessibleButton = uiradiobutton(app.Prob3_RadioButtons);
            app.rdMostAccessibleButton.Text = '3rd Most Accessible';
            app.stMostAccessibleButton = uiradiobutton(app.Prob3_RadioButtons);
            app.stMostAccessibleButton.Text = '1st Most Accessible ';
            app.stMostAccessibleButton.Value = true;
            app.ndMostAccesibleButton = uiradiobutton(app.Prob3_RadioButtons);
            app.ndMostAccesibleButton.Text = '2nd Most Accesible';
            app.thMostAccessibleButton = uiradiobutton(app.Prob3_RadioButtons);
            app.thMostAccessibleButton.Text = '4th Most Accessible';
            app.Prob3_Geoplot = uipanel(app.Problem3Tab);
            app.Prob3_Geoplot.Title = 'Visual of most prominent Energy Source';
            app.Load3 = uiimage(app.Problem3Tab);
            app.Load3.ImageSource = fullfile(pathToMLAPP, 'animated_loader_gif_n6b5x0.gif');
            app.Problem4Tab = uitab(app.TabGroup);
            app.Problem4Tab.Title = 'Problem 4';
            app.Prob4_Graph = uiaxes(app.Problem4Tab);
            title(app.Prob4_Graph, 'Accessibility by Income')
            ylabel(app.Prob4_Graph, 'Accessibility (%)')
            zlabel(app.Prob4_Graph, 'Z')
            app.Prob4_Graph.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.Prob4_IncomeStatus1 = uidropdown(app.Problem4Tab);
            app.Prob4_IncomeStatus1.Items = {'Low Income', 'Lower Middle Income', 'Upper Middle Income', 'High Income'};
            app.Prob4_IncomeStatus1.ValueChangedFcn = createCallbackFcn(app, @Prob4_IncomeStatus1ValueChanged, true);
            app.Prob4_IncomeStatus1.Value = 'Low Income';
            app.Prob4_YearRange = uislider(app.Problem4Tab, 'range');
            app.Prob4_YearRange.Limits = [1990 2023];
            app.Prob4_YearRange.ValueChangedFcn = createCallbackFcn(app, @Prob4_YearRangeValueChanged, true);
            app.Prob4_YearRange.Value = [1990 1990];
            app.Prob4_IncomeStatus2 = uidropdown(app.Problem4Tab);
            app.Prob4_IncomeStatus2.Items = {'Low Income', 'Lower Middle Income', 'Upper Middle Income', 'High Income'};
            app.Prob4_IncomeStatus2.ValueChangedFcn = createCallbackFcn(app, @Prob4_IncomeStatus2ValueChanged, true);
            app.Prob4_IncomeStatus2.Value = 'Low Income';
            app.Prob4_GraphButton = uibutton(app.Problem4Tab, 'push');
            app.Prob4_GraphButton.ButtonPushedFcn = createCallbackFcn(app, @Prob4_GraphButtonPushed, true);
            app.Prob4_GraphButton.Text = 'GRAPH';
            app.Load4 = uiimage(app.Problem4Tab);
            app.Load4.ImageSource = fullfile(pathToMLAPP, 'animated_loader_gif_n6b5x0.gif');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Access2Electricity

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end